classdef articulated_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        donvia                       matlab.ui.control.Label
        donviv                       matlab.ui.control.Label
        FowardKinematics             matlab.ui.control.Label
        GoHomeButton                 matlab.ui.control.Button
        TrajectoryPlanningButton     matlab.ui.control.Button
        amaxValue                    matlab.ui.control.NumericEditField
        vmaxValue                    matlab.ui.control.NumericEditField
        amaxLabel                    matlab.ui.control.Label
        vmaxLabel                    matlab.ui.control.Label
        DifferentialKinematicsLabel  matlab.ui.control.Label
        zLabel_4                     matlab.ui.control.Label
        yLabel_4                     matlab.ui.control.Label
        xLabel_4                     matlab.ui.control.Label
        zValue_2                     matlab.ui.control.NumericEditField
        yValue_2                     matlab.ui.control.NumericEditField
        xValue_2                     matlab.ui.control.NumericEditField
        zfLabel                      matlab.ui.control.Label
        yfLabel                      matlab.ui.control.Label
        xfLabel                      matlab.ui.control.Label
        zset2                        matlab.ui.control.NumericEditField
        yset2                        matlab.ui.control.NumericEditField
        xset2                        matlab.ui.control.NumericEditField
        OffWorkspaceButton           matlab.ui.control.Button
        DrawWorkspaceButton          matlab.ui.control.Button
        Inverse_Kinematics           matlab.ui.control.Label
        zLabel_2                     matlab.ui.control.Label
        yLabel_2                     matlab.ui.control.Label
        xLabel_2                     matlab.ui.control.Label
        xset                         matlab.ui.control.NumericEditField
        yset                         matlab.ui.control.NumericEditField
        zset                         matlab.ui.control.NumericEditField
        InverseButton                matlab.ui.control.Button
        YawLabel                     matlab.ui.control.Label
        PitchLabel                   matlab.ui.control.Label
        zLabel                       matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        xLabel                       matlab.ui.control.Label
        RollLabel                    matlab.ui.control.Label
        EndEffectorPoseOrientationLabel  matlab.ui.control.Label
        yawValue                     matlab.ui.control.NumericEditField
        pitchValue                   matlab.ui.control.NumericEditField
        zValue                       matlab.ui.control.NumericEditField
        yValue                       matlab.ui.control.NumericEditField
        xValue                       matlab.ui.control.NumericEditField
        rollValue                    matlab.ui.control.NumericEditField
        theta3Value                  matlab.ui.control.NumericEditField
        theta2Value                  matlab.ui.control.NumericEditField
        theta1Value                  matlab.ui.control.NumericEditField
        ForwardButton                matlab.ui.control.Button
        theta3Slider                 matlab.ui.control.Slider
        Theta3SliderLabel            matlab.ui.control.Label
        theta2Slider                 matlab.ui.control.Slider
        Theta2SliderLabel            matlab.ui.control.Label
        theta1Slider                 matlab.ui.control.Slider
        Theta1SliderLabel            matlab.ui.control.Label
        Theta3ddotGraph              matlab.ui.control.UIAxes
        Theta2ddotGraph              matlab.ui.control.UIAxes
        Theta1ddotGraph              matlab.ui.control.UIAxes
        Theta2dotGraph               matlab.ui.control.UIAxes
        Theta1dotGraph               matlab.ui.control.UIAxes
        Theta3dotGraph               matlab.ui.control.UIAxes
        Theta1Graph                  matlab.ui.control.UIAxes
        Theta3Graph                  matlab.ui.control.UIAxes
        Theta2Graph                  matlab.ui.control.UIAxes
        VelocityGraph                matlab.ui.control.UIAxes
        RobotGraph                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        WorkspacePlot % Object chứa workspace
        theta1_current = 0; % Góc hiện tại của khớp 1
        theta2_current = 0; % Góc hiện tại của khớp 2
        theta3_current = 0; % Góc hiện tại của khớp 3
        end_effector_path = [];
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Tham số DH cho mỗi khớp (d, theta, a, alpha)
            %d1 = 312; a1 = 0; alpha1 = pi/2;
            %d2 = 0; a2 = 410; alpha2 = 0;
            %d3 = 0; a3 = 448; alpha3 = 0;

            d1 = 0.5; a1 = 0; alpha1 = pi/2;
            d2 = 0; a2 = 0.5; alpha2 = 0;
            d3 = 0; a3 = 0.5; alpha3 = 0;
        
            x0 = 0;
            y0 = 0;
            z0 = 0;
        
            base_pose = [x0; y0; z0; 1];
        
            % Ma trận chuyển đổi cho từng khớp (Ban đầu
            % theta1=theta2=theta3 = 0)
            A1 = DH_Matrix(d1, 0, a1, alpha1);  
            A2 = DH_Matrix(d2, 0, a2, alpha2);
            A3 = DH_Matrix(d3, 0, a3, alpha3);
        
            % Ma trận DH từ base tới end-effector
            T = A1 * A2 * A3;
            
            l1_pose = A1*base_pose;
            x1 = l1_pose(1);
            y1 = l1_pose(2);
            z1 = l1_pose(3);
        
            l2_pose = A1*A2*base_pose;
            x2 = l2_pose(1);
            y2 = l2_pose(2);
            z2 = l2_pose(3);
            
            % Tọa độ của end-effector
            end_pose = T*base_pose;
            x3 = end_pose(1);
            y3 = end_pose(2);
            z3 = end_pose(3);
        
            %Tính Roll - Pitch - Yaw
            rpy = CaculateR_P_Y(T);
            roll = rpy(1);
            pitch = rpy(2);
            yaw = rpy(3);
        
            % Xóa trục cũ và thiết lập chế độ 3D
            cla(app.RobotGraph);  % Xóa trục trước khi vẽ mới
            hold(app.RobotGraph, 'on');
            grid(app.RobotGraph, 'on'); % Bật lưới
            view(app.RobotGraph, 3);    % Đặt chế độ xem 3D
        
            % Vẽ robot trong không gian 3D
            plot3(app.RobotGraph, [0, x0], [0, y0], [0, z0], 'black', 'LineWidth', 4); % Đoạn nối 0
            plot3(app.RobotGraph, [x0, x1], [y0, y1], [z0, z1], 'r-', 'LineWidth', 4); % Đoạn nối 1
            plot3(app.RobotGraph, [x1, x2], [y1, y2], [z1, z2], 'g-', 'LineWidth', 4); % Đoạn nối 2
            plot3(app.RobotGraph, [x2, x3], [y2, y3], [z2, z3], 'b-', 'LineWidth', 4); % Đoạn nối 3
            
            %Vẽ base
            % Thiết lập bán kính và số lượng điểm cho hình tròn
            r = 0.3; % Bán kính hình tròn
            goc = linspace(0, 2*pi, 100); % Góc từ 0 đến 2*pi
            x_circle = x0 + r * cos(goc); % Tọa độ x của hình tròn
            y_circle = y0 + r * sin(goc); % Tọa độ y của hình tròn
            plot(app.RobotGraph, x_circle, y_circle, 'b-', 'LineWidth', 2); % Base

            plot3(app.RobotGraph, x1, y1, z1, 'ko', 'MarkerSize', 4, 'MarkerFaceColor', 'k'); % Joint 1
            plot3(app.RobotGraph, x2, y2, z2, 'ko', 'MarkerSize', 4, 'MarkerFaceColor', 'k'); % Joint 2
            plot3(app.RobotGraph, x3, y3, z3, 'ko', 'MarkerSize', 8, 'MarkerFaceColor', 'k'); % End - effector
        
            % Thiết lập giới hạn cho trục
            axis(app.RobotGraph, [-2 2 -2 2 0 2]);
            hold(app.RobotGraph, 'off'); % Kết thúc giữ đồ thị
            
            app.xValue.Value = x3;
            app.yValue.Value = y3;
            app.zValue.Value = z3;
            %Value_2 là ô chứa giá trị nằm ở khung Differential Kinematics
            app.xValue_2.Value = x3;
            app.yValue_2.Value = y3;
            app.zValue_2.Value = z3;
            app.rollValue.Value = roll;
            app.pitchValue.Value = pitch;
            app.yawValue.Value = yaw;
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            % robot di chuyển đều theo số steps
            steps = 20;

            % Lấy giá trị các góc target từ slider
            theta1_target = deg2rad(app.theta1Slider.Value);
            theta2_target = deg2rad(app.theta2Slider.Value);
            theta3_target = deg2rad(app.theta3Slider.Value);
        
            % Giá trị hiện tại
            theta1_current = app.theta1_current;
            theta2_current = app.theta2_current;
            theta3_current = app.theta3_current;
        
            for i = 1:steps
                % Tính lại các góc ở step thứ i
                theta1 = theta1_current + (theta1_target - theta1_current) * (i / steps);
                theta2 = theta2_current + (theta2_target - theta2_current) * (i / steps);
                theta3 = theta3_current + (theta3_target - theta3_current) * (i / steps);
                
                %luu lai cac gia tri theta cho lan sau
                app.theta1_current = theta1;
                app.theta2_current = theta2;
                app.theta3_current = theta3;
                
                % Cập nhật tọa độ end-effector
                updateRobotPose(app, theta1, theta2, theta3);
                pause(0.05); % Điều chỉnh tốc độ di chuyển
            end
        end

        % Value changed function: theta1Slider
        function theta1SliderValueChanged(app, event)
            app.theta1Value.Value = app.theta1Slider.Value;
        end

        % Value changed function: theta2Slider
        function theta2SliderValueChanged(app, event)
            app.theta2Value.Value = app.theta2Slider.Value;
        end

        % Value changed function: theta3Slider
        function theta3SliderValueChanged(app, event)
            app.theta3Value.Value = app.theta3Slider.Value;
        end

        % Value changed function: theta1Value
        function theta1ValueValueChanged(app, event)
            app.theta1Slider.Value = app.theta1Value.Value;
        end

        % Value changed function: theta2Value
        function theta2ValueValueChanged(app, event)
            app.theta2Slider.Value = app.theta2Value.Value;
        end

        % Value changed function: theta3Value
        function theta3ValueValueChanged(app, event)
            app.theta3Slider.Value = app.theta3Value.Value;
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            % Giá trị hiện tại (sử dụng từ các thuộc tính lưu trữ)
            theta1_current = app.theta1_current;
            theta2_current = app.theta2_current;
            theta3_current = app.theta3_current;
        
            % Số bước để di chuyển từ từ
            steps = 20;
            
            theta_target = GetInverse(app);
            theta1_target = theta_target(1);
            theta2_target = theta_target(2);
            theta3_target = theta_target(3);

            % Di chuyển từ từ đến vị trí mới
            for i = 1:steps
                % Tính toán các góc trung gian
                theta1 = theta1_current + (theta1_target - theta1_current) * (i / steps);
                theta2 = theta2_current + (theta2_target - theta2_current) * (i / steps);
                theta3 = theta3_current + (theta3_target - theta3_current) * (i / steps);
        
                % Lưu lại các giá trị theta cho lần sau
                app.theta1_current = theta1;
                app.theta2_current = theta2;
                app.theta3_current = theta3;
        
                % Cập nhật giá trị trên giao diện
                app.theta1Slider.Value = rad2deg(theta1);
                app.theta2Slider.Value = rad2deg(theta2);
                app.theta3Slider.Value = rad2deg(theta3);
                app.theta1Value.Value = rad2deg(theta1);
                app.theta2Value.Value = rad2deg(theta2);
                app.theta3Value.Value = rad2deg(theta3);
        
                % Cập nhật tọa độ end-effector
                updateRobotPose(app, theta1, theta2, theta3);
                pause(0.05); % Điều chỉnh tốc độ di chuyển
            end
        end

        % Button pushed function: DrawWorkspaceButton
        function DrawWorkspaceButtonPushed(app, event)
            plotWorkspace(app);
        end

        % Button pushed function: OffWorkspaceButton
        function OffWorkspaceButtonPushed(app, event)
            removeWorkspace(app);
        end

        % Button pushed function: TrajectoryPlanningButton
        function TrajectoryPlanningButtonPushed(app, event)
            differentialKinematics(app);
        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            % robot di chuyển đều theo số steps
            steps = 20;

            % Lấy giá trị các góc target từ slider
            theta1_target = 0;
            theta2_target = 0;
            theta3_target = 0;
        
            % Giá trị hiện tại
            theta1_current = app.theta1_current;
            theta2_current = app.theta2_current;
            theta3_current = app.theta3_current;
        
            for i = 1:steps
                % Tính lại các góc ở step thứ i
                theta1 = theta1_current + (theta1_target - theta1_current) * (i / steps);
                theta2 = theta2_current + (theta2_target - theta2_current) * (i / steps);
                theta3 = theta3_current + (theta3_target - theta3_current) * (i / steps);
                
                %luu lai cac gia tri theta cho lan sau
                app.theta1_current = theta1;
                app.theta2_current = theta2;
                app.theta3_current = theta3;

                % Cập nhật giá trị trên giao diện
                app.theta1Slider.Value = rad2deg(theta1);
                app.theta2Slider.Value = rad2deg(theta2);
                app.theta3Slider.Value = rad2deg(theta3);
                app.theta1Value.Value = rad2deg(theta1);
                app.theta2Value.Value = rad2deg(theta2);
                app.theta3Value.Value = rad2deg(theta3);
                
                % Cập nhật tọa độ end-effector
                updateRobotPose(app, theta1, theta2, theta3);
                pause(0.05); % Điều chỉnh tốc độ di chuyển
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RobotGraph = uiaxes(app.UIFigure);
            title(app.RobotGraph, 'Articulated Arm')
            xlabel(app.RobotGraph, 'X(m)')
            ylabel(app.RobotGraph, 'Y(m)')
            zlabel(app.RobotGraph, 'Z(m)')
            app.VelocityGraph = uiaxes(app.UIFigure);
            title(app.VelocityGraph, 'Vận tốc end-effector')
            xlabel(app.VelocityGraph, 't')
            ylabel(app.VelocityGraph, 'v')
            app.VelocityGraph.ZTick = [];
            app.Theta2Graph = uiaxes(app.UIFigure);
            title(app.Theta2Graph, 'Theta2')
            xlabel(app.Theta2Graph, 't')
            ylabel(app.Theta2Graph, 'theta2')
            app.Theta2Graph.ZTick = [];
            app.Theta3Graph = uiaxes(app.UIFigure);
            title(app.Theta3Graph, 'Theta3')
            xlabel(app.Theta3Graph, 't')
            ylabel(app.Theta3Graph, 'theta3')
            app.Theta3Graph.ZTick = [];
            app.Theta1Graph = uiaxes(app.UIFigure);
            title(app.Theta1Graph, 'Theta1')
            xlabel(app.Theta1Graph, 't')
            ylabel(app.Theta1Graph, 'theta1')
            zlabel(app.Theta1Graph, 'Z')
            app.Theta3dotGraph = uiaxes(app.UIFigure);
            title(app.Theta3dotGraph, 'Theta3dot')
            xlabel(app.Theta3dotGraph, 't')
            ylabel(app.Theta3dotGraph, 'theta3dot')
            app.Theta3dotGraph.ZTick = [];
            app.Theta1dotGraph = uiaxes(app.UIFigure);
            title(app.Theta1dotGraph, 'Theta1dot')
            xlabel(app.Theta1dotGraph, 't')
            ylabel(app.Theta1dotGraph, 'theta1dot')
            zlabel(app.Theta1dotGraph, 'Z')
            app.Theta2dotGraph = uiaxes(app.UIFigure);
            title(app.Theta2dotGraph, 'Theta2dot')
            xlabel(app.Theta2dotGraph, 't')
            ylabel(app.Theta2dotGraph, 'theta2dot')
            app.Theta2dotGraph.ZTick = [];
            app.Theta1ddotGraph = uiaxes(app.UIFigure);
            title(app.Theta1ddotGraph, 'Theta1ddot')
            xlabel(app.Theta1ddotGraph, 't')
            ylabel(app.Theta1ddotGraph, 'theta1ddot')
            zlabel(app.Theta1ddotGraph, 'Z')
            app.Theta2ddotGraph = uiaxes(app.UIFigure);
            title(app.Theta2ddotGraph, 'Theta2ddot')
            xlabel(app.Theta2ddotGraph, 't')
            ylabel(app.Theta2ddotGraph, 'theta2ddot')
            app.Theta2ddotGraph.ZTick = [];
            app.Theta3ddotGraph = uiaxes(app.UIFigure);
            title(app.Theta3ddotGraph, 'Theta3ddot')
            xlabel(app.Theta3ddotGraph, 't')
            ylabel(app.Theta3ddotGraph, 'theta3ddot')
            app.Theta3ddotGraph.ZTick = [];
            app.Theta1SliderLabel = uilabel(app.UIFigure);
            app.Theta1SliderLabel.Text = 'Theta1';
            app.theta1Slider = uislider(app.UIFigure);
            app.theta1Slider.Limits = [-180 180];
            app.theta1Slider.ValueChangedFcn = createCallbackFcn(app, @theta1SliderValueChanged, true);
            app.Theta2SliderLabel = uilabel(app.UIFigure);
            app.Theta2SliderLabel.Text = 'Theta2';
            app.theta2Slider = uislider(app.UIFigure);
            app.theta2Slider.Limits = [-180 180];
            app.theta2Slider.ValueChangedFcn = createCallbackFcn(app, @theta2SliderValueChanged, true);
            app.Theta3SliderLabel = uilabel(app.UIFigure);
            app.Theta3SliderLabel.Text = 'Theta3';
            app.theta3Slider = uislider(app.UIFigure);
            app.theta3Slider.Limits = [-180 180];
            app.theta3Slider.ValueChangedFcn = createCallbackFcn(app, @theta3SliderValueChanged, true);
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.theta1Value = uieditfield(app.UIFigure, 'numeric');
            app.theta1Value.ValueChangedFcn = createCallbackFcn(app, @theta1ValueValueChanged, true);
            app.theta2Value = uieditfield(app.UIFigure, 'numeric');
            app.theta2Value.ValueChangedFcn = createCallbackFcn(app, @theta2ValueValueChanged, true);
            app.theta3Value = uieditfield(app.UIFigure, 'numeric');
            app.theta3Value.ValueChangedFcn = createCallbackFcn(app, @theta3ValueValueChanged, true);
            app.rollValue = uieditfield(app.UIFigure, 'numeric');
            app.xValue = uieditfield(app.UIFigure, 'numeric');
            app.yValue = uieditfield(app.UIFigure, 'numeric');
            app.zValue = uieditfield(app.UIFigure, 'numeric');
            app.pitchValue = uieditfield(app.UIFigure, 'numeric');
            app.yawValue = uieditfield(app.UIFigure, 'numeric');
            app.EndEffectorPoseOrientationLabel = uilabel(app.UIFigure);
            app.EndEffectorPoseOrientationLabel.Text = 'End-Effector Pose & Orientation';
            app.RollLabel = uilabel(app.UIFigure);
            app.RollLabel.Text = 'Roll';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.UIFigure);
            app.zLabel.Text = 'z';
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch';
            app.YawLabel = uilabel(app.UIFigure);
            app.YawLabel.Text = 'Yaw';
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.zset = uieditfield(app.UIFigure, 'numeric');
            app.yset = uieditfield(app.UIFigure, 'numeric');
            app.xset = uieditfield(app.UIFigure, 'numeric');
            app.xLabel_2 = uilabel(app.UIFigure);
            app.xLabel_2.Text = 'x';
            app.yLabel_2 = uilabel(app.UIFigure);
            app.yLabel_2.Text = 'y';
            app.zLabel_2 = uilabel(app.UIFigure);
            app.zLabel_2.Text = 'z';
            app.Inverse_Kinematics = uilabel(app.UIFigure);
            app.Inverse_Kinematics.Text = 'Inverse Kinematics';
            app.DrawWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.DrawWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @DrawWorkspaceButtonPushed, true);
            app.DrawWorkspaceButton.Text = 'DrawWorkspace';
            app.OffWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.OffWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @OffWorkspaceButtonPushed, true);
            app.OffWorkspaceButton.Text = 'OffWorkspace';
            app.xset2 = uieditfield(app.UIFigure, 'numeric');
            app.yset2 = uieditfield(app.UIFigure, 'numeric');
            app.zset2 = uieditfield(app.UIFigure, 'numeric');
            app.xfLabel = uilabel(app.UIFigure);
            app.xfLabel.Text = 'xf';
            app.yfLabel = uilabel(app.UIFigure);
            app.yfLabel.Text = 'yf';
            app.zfLabel = uilabel(app.UIFigure);
            app.zfLabel.Text = 'zf';
            app.xValue_2 = uieditfield(app.UIFigure, 'numeric');
            app.yValue_2 = uieditfield(app.UIFigure, 'numeric');
            app.zValue_2 = uieditfield(app.UIFigure, 'numeric');
            app.xLabel_4 = uilabel(app.UIFigure);
            app.xLabel_4.Text = 'x';
            app.yLabel_4 = uilabel(app.UIFigure);
            app.yLabel_4.Text = 'y';
            app.zLabel_4 = uilabel(app.UIFigure);
            app.zLabel_4.Text = 'z';
            app.DifferentialKinematicsLabel = uilabel(app.UIFigure);
            app.DifferentialKinematicsLabel.Text = 'Differential Kinematics';
            app.vmaxLabel = uilabel(app.UIFigure);
            app.vmaxLabel.Text = 'vmax';
            app.amaxLabel = uilabel(app.UIFigure);
            app.amaxLabel.Text = 'amax';
            app.vmaxValue = uieditfield(app.UIFigure, 'numeric');
            app.amaxValue = uieditfield(app.UIFigure, 'numeric');
            app.TrajectoryPlanningButton = uibutton(app.UIFigure, 'push');
            app.TrajectoryPlanningButton.ButtonPushedFcn = createCallbackFcn(app, @TrajectoryPlanningButtonPushed, true);
            app.TrajectoryPlanningButton.Text = 'Trajectory Planning';
            app.GoHomeButton = uibutton(app.UIFigure, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Text = 'GoHome';
            app.FowardKinematics = uilabel(app.UIFigure);
            app.FowardKinematics.Text = 'Foward Kinematics';
            app.donviv = uilabel(app.UIFigure);
            app.donviv.Text = 'm/s';
            app.donvia = uilabel(app.UIFigure);
            app.donvia.Text = 'm/s2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = articulated_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end